classdef houghcircle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        r_max_value            matlab.ui.control.NumericEditField
        Label_14               matlab.ui.control.Label
        EditField_5            matlab.ui.control.EditField
        EditField_4            matlab.ui.control.EditField
        Switch                 matlab.ui.control.Switch
        r_min_value_3          matlab.ui.control.NumericEditField
        Label_13               matlab.ui.control.Label
        r_min_value_2          matlab.ui.control.NumericEditField
        Label_12               matlab.ui.control.Label
        Slider_7               matlab.ui.control.Slider
        Label_11               matlab.ui.control.Label
        Button_4               matlab.ui.control.Button
        Button_3               matlab.ui.control.Button
        Button_2               matlab.ui.control.Button
        Button                 matlab.ui.control.Button
        Slider_6               matlab.ui.control.Slider
        Slider_6Label          matlab.ui.control.Label
        Slider_5               matlab.ui.control.Slider
        Label_10               matlab.ui.control.Label
        StarProssecing_2       matlab.ui.control.Button
        EditField_3            matlab.ui.control.NumericEditField
        Label_9                matlab.ui.control.Label
        Slider_4               matlab.ui.control.Slider
        Label_8                matlab.ui.control.Label
        Slider_3               matlab.ui.control.Slider
        Label_7                matlab.ui.control.Label
        Slider_2               matlab.ui.control.Slider
        Label_6                matlab.ui.control.Label
        Slider                 matlab.ui.control.Slider
        Label_5                matlab.ui.control.Label
        EditField_2            matlab.ui.control.NumericEditField
        EditField_2Label_2     matlab.ui.control.Label
        EditField              matlab.ui.control.NumericEditField
        Label_4                matlab.ui.control.Label
        r_min_value            matlab.ui.control.NumericEditField
        Label_3                matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        r_max                  matlab.ui.control.Slider
        Label                  matlab.ui.control.Label
        houghTranslation       matlab.ui.control.Button
        StarProssecing         matlab.ui.control.Button
        r_min                  matlab.ui.control.Slider
        PredefinedImagesLabel  matlab.ui.control.Label
        DropDown               matlab.ui.control.DropDown
        LoadButton             matlab.ui.control.Button
        Enlarge                matlab.ui.control.UIAxes
        ImageAfterHough_2      matlab.ui.control.UIAxes
        UIAxes_6               matlab.ui.control.UIAxes
        UIAxes_5               matlab.ui.control.UIAxes
        UIAxes_4               matlab.ui.control.UIAxes
        UIAxes_3               matlab.ui.control.UIAxes
        UIAxes_2               matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
        ImageAfterHough        matlab.ui.control.UIAxes
        ImageOrigin            matlab.ui.control.UIAxes
        ContextMenu            matlab.ui.container.ContextMenu
        Menu                   matlab.ui.container.Menu
        Menu2                  matlab.ui.container.Menu
    end

    
    properties (Access = private)
        im_data % 图像数据
        im_processing_data %预处理后数据
        im_picdata %预处理图像数据cell数组
        radius_range = [40, 200]
        %r_num 
        numpeaks = 1;
        %peaksthreshold 
        circle_num = 5 %想要画几个圆
        division_times =50 %步进分割大小
        hsize = 15 %高斯参数
        sigma = 15 %高斯参数
        level = 12  %灰度分割
        levelThresh = 8 %灰度选取阈值
        Centers % 圆心坐标（Y,X),投票数，半径 数组
        imopen_range = 3 % Description
        imclose_range = 10 % Description
        p_thresh =[0.1,0.2]
        p = 128
        Centers_save
        method = 0 % 0调用默认方法，1调用p阈值方法
    end
    
    methods (Access = private)
        
        function [] = SaveMinCircle(app,centers)
            vote_list = sortrows(centers,4,"ascend");
            x_min = vote_list(1,1);
            y_min = vote_list(1,2);
            r_min = vote_list(1,4);
            app.Centers_save(1,1:4) = [x_min,y_min,0,r_min];
            imshow(app.im_data,"Parent",app.ImageAfterHough_2);
            viscircles(app.ImageAfterHough_2,fliplr(app.Centers_save(:,1:2)),app.Centers_save(:,4));
        end

        function [] = SaveMaxCircle(app,centers)
            vote_list = sortrows(centers,4,"descend");
            x_max = vote_list(1,1);
            y_max = vote_list(1,2);
            r_max = vote_list(1,4);
            app.Centers_save(2,1:4) = [x_max,y_max,0,r_max];
            imshow(app.im_data,"Parent",app.ImageAfterHough_2);
            viscircles(app.ImageAfterHough_2,fliplr(app.Centers_save(:,1:2)),app.Centers_save(:,4));
        end

        function [] = Cut(app)
            Mask(app,app.im_data,app.Centers_save);
        end

        function PicData = Preprossecing(app,im,hsize,sigma,level,levelThresh)
            % 1原图 2高斯处理 3分层上色 4阈值划分 5开运算 6闭运算 7canny
            if ndims(im)==3
                im = rgb2gray(im);
            end
            
            PicData = {};
            PicData{1} = im;
            gaussianFilter = fspecial('gaussian',hsize, sigma);
            gaus = imfilter(im, gaussianFilter,'symmetric');
            PicData{2} = uint8(gaus);

            levels = multithresh(im, level);
            BW = imquantize(gaus,levels);
            L = imfill(BW);
            Colored_data = label2rgb(L);
            PicData{3} = Colored_data;

            BW(BW<levelThresh)=0;
            BW(BW>=levelThresh)=1;
            PicData{4} = BW;

            if app.method == 1
                BW= gaus >= app.p;
                PicData{3} = zeros(size(BW));
                PicData{4} = BW;
            end 

            se = strel('disk',app.imopen_range);
            ED = imopen(BW, se);
            PicData{5} = ED;

            se = strel('disk',app.imclose_range);
            ED2 = imclose(ED, se);
            PicData{6} = ED2;

            img_edges = edge(ED2, 'Canny');
            PicData{7} = img_edges;

            if app.method == 1
                img_edges=edge(ED2,'canny',app.p_thresh,sqrt(2)); 
                PicData{7} = img_edges;
            end
            app.im_processing_data = img_edges;
            app.im_picdata = PicData;
        end
        
        function [] = Mask(app,picdata,centers)
            %centers 一二列记录坐标（y,x），第三列为票数,第四列为半径

            vote_list = sortrows(centers,4,"ascend")
            y_min = vote_list(1,1);
            x_min = vote_list(1,2);
            r_min = vote_list(1,4);
            app.Centers_save(1,:) = vote_list(1,:);
            vote_list = sortrows(centers,4,"descend")
            y_max = vote_list(1,1);
            x_max = vote_list(1,2);
            r_max = vote_list(1,4);
            app.Centers_save(2,:) = vote_list(1,:);

            imsize = size(picdata);
            mask1 = false(imsize); 
            %取外圆内围
            for i = 1:imsize(1)%high 
                for j = 1:imsize(2)%wight
                    mask1(i, j) = sqrt((j-x_min)^2+(i-y_min)^2) > r_min;
                end
            end
            mask2 = false(imsize); 
            %取内圆外围
            for i = 1:imsize(1)%high
                for j = 1:imsize(2)%wight
                    mask2(i, j) = sqrt((j-x_max)^2+(i-y_max)^2) < r_max;
                end
            end

            maskim = picdata .* uint8(mask1) .* uint8(mask2);
            imshow(maskim,"Parent",app.ImageAfterHough_2);

            app.EditField_4.Value = app.Centers_save(1,1)+","+app.Centers_save(1,2)+","+round(app.Centers_save(1,4));
            app.EditField_5.Value = app.Centers_save(2,1)+","+app.Centers_save(2,2)+","+round(app.Centers_save(2,4));
        end

        function [centers] = HoughCircle(~,data,radius_range,r_num,numpeaks,peaksthreshold,circle_num,division_times)
            %center 一二列记录坐标，第三列为票数,第四列为半径

            %radius_range = [40, 200];
            r_min = radius_range(1);
            r_max = radius_range(2);
            %r_num = 1;
            %numpeaks = 1;%同一个半径查找几次圆
            %peaksthreshold = 0.8;
            centers = zeros(r_num * numpeaks, 4);%一二列记录坐标，第三列为票数,第四列为半径
            %radii = zeros(size(centers,1),1); 优化删除
            row_num = 0;
            %circle_num=2;%想要画几个圆
            %division_times = 100;%步进大小
                for radius = linspace(r_min, r_max, division_times) %半径的步进大小,除以(n+1)，得到N个分割数值
                    % Compute Hough accumulator array for finding circles.
                    H = zeros(size(data));%投票矩阵/参数矩阵
                    for x = 1 : size(data, 2)
                        for y = 1 : size(data, 1)
                            if (data(y,x))
                                for theta = linspace(0, 2 * pi, 360)
                                    a = round(x + radius * cos(theta));                
                                    b = round(y + radius * sin(theta));
                                    if (a > 0 && a <= size(H, 2) && b > 0 && b <= size(H,1))%圆心坐标在图像范围内
                                        H(b,a) = H(b,a) + 1;
                                    end
                                end
                            end
                        end
                    end
            
                    % Find peaks in a Hough accumulator array
                    threshold = peaksthreshold * max(H(:)); % which values of H are considered to be peaks
                    %max(H(:));
                    nHoodSize = floor(size(H) / 100.0) * 2 + 1; % Size of the suppression neighborhood, [M N]
                    peaks = zeros(numpeaks, 4);
                    num = 0;
                    while(num < numpeaks) %指定半径下，寻找多少次峰值点
                        maxH = max(H(:));
                        if (maxH >= threshold)
                            num = num + 1;
                            [ra,c] = find(H == maxH);
                            peaks(num,:) = [ra(1),c(1),max(H(:)),radius]; %记录圆心，票数，半径
                            rStart = max(1, ra - (nHoodSize(1) - 1) / 2);
                            rEnd = min(size(H,1), ra + (nHoodSize(1) - 1) / 2);
                            cStart = max(1, c - (nHoodSize(2) - 1) / 2);
                            cEnd = min(size(H,2), c + (nHoodSize(2) - 1) / 2);
                            for i = rStart : rEnd   %多次寻找峰值点下，消去同个圆心周围区域影响
                                for j = cStart : cEnd
                                        H(i,j) = 0;
                                end
                            end
                        else
                            break;          
                        end
                    end
                    peaks = peaks(1:num, :);        
                    if (size(peaks,1) > 0) %peaks多少行，即测出多少个peaks
                        row_num_new = row_num + size(peaks,1);
                        centers(row_num + 1:row_num_new,:) = peaks;%迭代统计入centers
                        %radii(row_num + 1:row_num_new) = radius;
                        row_num = row_num_new;       
                    end
                    
                end
            centers = centers(1:row_num,:);
            %radii = centers(1:row_num,4); 半径存入centers的第四列
            %centers %test
            vote_list = sortrows(centers,3,"descend");
            centers = vote_list(1:circle_num,:); %取指定数量的圆
            %centers %test
 

        end


        function updateimage(app,imagefile)
            
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end            
                    if ndims(im)==3
                        im = rgb2gray(im);
                    end
                    app.im_data = uint8(im);

                    % Display the truecolor image
                    imagesc(app.ImageOrigin,im);
                    
                    %gray = rgb2gray(im);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageOrigin.Visible = 'off';
            app.ImageOrigin.Colormap = gray(256);
            axis(app.ImageOrigin, 'image');
            
            % Update the image and histograms
            %updateimage(app, '1.bmp');
            updateimage(app, '2.png');
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif;*.bmp','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end

        % Callback function: Slider, Slider_2, Slider_3, Slider_4,
        % Slider_5, 
        % ...and 2 other components
        function StarProssecingButtonPushed(app, event)
            app.Switch.Value = "0";
            Data = Preprossecing(app,app.im_data,app.hsize,app.sigma,app.level,app.levelThresh);
            % Data(1)原图 2高斯处理 3分层上色 4阈值划分 5开运算 6闭运算 7canny
            imshow(Data{2},"Parent",app.UIAxes);
            imshow(Data{3},"Parent",app.UIAxes_2);
            imshow(Data{4},"Parent",app.UIAxes_3);
            imshow(Data{5},"Parent",app.UIAxes_4);
            imshow(Data{6},"Parent",app.UIAxes_5);
            imshow(Data{7},"Parent",app.UIAxes_6);

            imshow(Data{7},"Parent",app.Enlarge);

            % Data(1)原图 2高斯处理 3分层上色 4阈值划分 5开运算 6闭运算 7canny
        end

        % Button pushed function: houghTranslation
        function houghTranslationButtonPushed(app, event)
            
            r_num = 1;
            %numpeaks = 1;
            peaksthreshold = 0.8;
            data = app.im_processing_data;
            [centers] = HoughCircle(app,data,app.radius_range,r_num,app.numpeaks, ...
                peaksthreshold,app.circle_num,app.division_times);
            app.Centers = centers;

            imshow(app.im_processing_data,"Parent",app.ImageAfterHough);
            
            viscircles(app.ImageAfterHough,fliplr(centers(:,1:2)),centers(:,4))


        end

        % Value changed function: r_min
        function r_minValueChanged(app, event)
            value = app.r_min.Value;
            app.radius_range(1) = round(value);
            app.r_min_value.Value = round(value);
        end

        % Callback function
        function r_minValueChanging(app, event)
            changingValue = event.Value;

        end

        % Value changed function: r_min_value
        function r_min_valueValueChanged(app, event)
            value = app.r_min_value.Value;
            app.radius_range(1) = round(value);
        end

        % Value changed function: r_max
        function r_maxValueChanged(app, event)
            value = app.r_max.Value;
            app.radius_range(2) = round(value);
            app.r_max_value.Value = round(value);
        end

        % Callback function
        function r_max_valueValueChanged(app, event)
            value = app.r_max_value.Value;
            app.radius_range(2) = round(value);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.circle_num = round(value);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.division_times = round(value);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.hsize = round(changingValue);
        end

        % Callback function
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.sigma = round(changingValue);
        end

        % Value changing function: Slider_3
        function Slider_3ValueChanging(app, event)
            changingValue = event.Value;
            app.level = round(changingValue);
        end

        % Value changing function: Slider_4
        function Slider_4ValueChanging(app, event)
            changingValue = event.Value;
            app.levelThresh = round(changingValue);
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            imshow(app.im_picdata{2},"Parent",app.Enlarge);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.numpeaks = round(value);
        end

        % Callback function
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.hsize = round(value);
            Preprossecing()
        end

        % Callback function
        function Slider_2ValueChanged2(app, event)
            value = app.Slider_2.Value;
            
        end

        % Button pushed function: StarProssecing_2
        function StarProssecing_2ButtonPushed(app, event)
            Mask(app,app.im_data,app.Centers);

        end

        % Value changing function: Slider_5
        function Slider_5ValueChanging(app, event)
            changingValue = event.Value;
            app.imopen_range = round(changingValue);
        end

        % Value changing function: Slider_6
        function Slider_6ValueChanging(app, event)
            changingValue = event.Value;
            app.imclose_range = round(changingValue);
        end

        % Callback function: Button, Slider_7
        function ButtonPushed(app, event)
            app.Switch.Value = "1";
            app.method = 1;
            Data = Preprossecing(app,app.im_data,app.hsize,app.sigma,app.level,app.levelThresh);
            % Data(1)原图 2高斯处理 3分层上色 4阈值划分 5开运算 6闭运算 7canny
            imshow(Data{2},"Parent",app.UIAxes);
            imshow(Data{3},"Parent",app.UIAxes_2);
            imshow(Data{4},"Parent",app.UIAxes_3);
            imshow(Data{5},"Parent",app.UIAxes_4);
            imshow(Data{6},"Parent",app.UIAxes_5);
            imshow(Data{7},"Parent",app.UIAxes_6);
            imshow(Data{7},"Parent",app.Enlarge);
        end

        % Callback function
        function Slider_7ValueChanged(app, event)
            value = app.Slider_7.Value;
            app.p = value;
        end

        % Value changing function: Slider_7
        function Slider_7ValueChanging(app, event)
            changingValue = event.Value;
            app.p = changingValue;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            SaveMinCircle(app,app.Centers);
            app.EditField_4.Value = app.Centers_save(1,1)+","+app.Centers_save(1,2)+","+round(app.Centers_save(1,4));
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            SaveMaxCircle(app,app.Centers);
            app.EditField_5.Value = app.Centers_save(2,1)+","+app.Centers_save(2,2)+","+round(app.Centers_save(2,4));
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            Cut(app);
        end

        % Value changed function: r_min_value_2
        function r_min_value_2ValueChanged(app, event)
            value = app.r_min_value_2.Value;
            app.p_thresh(1) = value;
        end

        % Value changed function: r_min_value_3
        function r_min_value_3ValueChanged(app, event)
            value = app.r_min_value_3.Value;
            app.p_thresh(2) = value;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.method = value=="1";
        end

        % Callback function
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            
        end

        % Value changed function: r_max_value
        function r_max_valueValueChanged2(app, event)
            value = app.r_max_value.Value;
            app.radius_range(1) = round(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.ImageOrigin = uiaxes(app.UIFigure);
            app.ImageOrigin.XTick = [];
            app.ImageOrigin.XTickLabel = {'[ ]'};
            app.ImageOrigin.YTick = [];
            app.ImageAfterHough = uiaxes(app.UIFigure);
            app.ImageAfterHough.XTick = [];
            app.ImageAfterHough.XTickLabel = {'[ ]'};
            app.ImageAfterHough.YTick = [];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '1、高斯滤波')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {'[ ]'};
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '2、分层上色')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = {'[ ]'};
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '3、阈值划分')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.XTickLabel = {'[ ]'};
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, '4、开运算')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.XTickLabel = {'[ ]'};
            app.UIAxes_4.YTick = [];
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, '5、闭运算')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.XTickLabel = {'[ ]'};
            app.UIAxes_5.YTick = [];
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, '6、Canny')
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.XTickLabel = {'[ ]'};
            app.UIAxes_6.YTick = [];
            app.ImageAfterHough_2 = uiaxes(app.UIFigure);
            app.ImageAfterHough_2.XTick = [];
            app.ImageAfterHough_2.XTickLabel = {'[ ]'};
            app.ImageAfterHough_2.YTick = [];
            app.Enlarge = uiaxes(app.UIFigure);
            title(app.Enlarge, 'Canny放大图像')
            app.Enlarge.XTick = [];
            app.Enlarge.XTickLabel = {'[ ]'};
            app.Enlarge.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = '载入自定义图片';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'1.bmp', '2.png', '3.jpg'};
            app.DropDown.ItemsData = {'1.bmp', '2.png', '3.jpg'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '1.bmp';
            app.PredefinedImagesLabel = uilabel(app.UIFigure);
            app.PredefinedImagesLabel.Text = 'Predefined Images';
            app.r_min = uislider(app.UIFigure);
            app.r_min.Limits = [0 200];
            app.r_min.ValueChangedFcn = createCallbackFcn(app, @r_minValueChanged, true);
            app.r_min.Value = 20;
            app.StarProssecing = uibutton(app.UIFigure, 'push');
            app.StarProssecing.ButtonPushedFcn = createCallbackFcn(app, @StarProssecingButtonPushed, true);
            app.StarProssecing.Text = '一、预处理';
            app.houghTranslation = uibutton(app.UIFigure, 'push');
            app.houghTranslation.ButtonPushedFcn = createCallbackFcn(app, @houghTranslationButtonPushed, true);
            app.houghTranslation.Text = '二、Hough取圆';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'搜索圆'; '半径最小值'};
            app.r_max = uislider(app.UIFigure);
            app.r_max.Limits = [0 200];
            app.r_max.ValueChangedFcn = createCallbackFcn(app, @r_maxValueChanged, true);
            app.r_max.Value = 100;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'搜索圆'; '半径最大值'};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '值';
            app.r_min_value = uieditfield(app.UIFigure, 'numeric');
            app.r_min_value.Limits = [0 500];
            app.r_min_value.ValueChangedFcn = createCallbackFcn(app, @r_min_valueValueChanged, true);
            app.r_min_value.Value = 20;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '画圆数量';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [0 100];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 5;
            app.EditField_2Label_2 = uilabel(app.UIFigure);
            app.EditField_2Label_2.Text = {'半径步进分割数量'; '（速度影响大）'};
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Limits = [0 500];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 50;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '高斯模糊半径';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 50];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @StarProssecingButtonPushed, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 15;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '高斯模糊强度';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [1 50];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @StarProssecingButtonPushed, true);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_2.Value = 15;
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '灰阶分层';
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [1 19];
            app.Slider_3.MajorTicks = [1 15 30];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @StarProssecingButtonPushed, true);
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Slider_3ValueChanging, true);
            app.Slider_3.Value = 12;
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = {'二值化'; '分层阈值'};
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_4.Limits = [1 20];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @StarProssecingButtonPushed, true);
            app.Slider_4.ValueChangingFcn = createCallbackFcn(app, @Slider_4ValueChanging, true);
            app.Slider_4.Value = 7;
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = {'同一半径'; '取圆数量'};
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Limits = [0 100];
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = 2;
            app.StarProssecing_2 = uibutton(app.UIFigure, 'push');
            app.StarProssecing_2.ButtonPushedFcn = createCallbackFcn(app, @StarProssecing_2ButtonPushed, true);
            app.StarProssecing_2.Text = '三、裁切内外圆';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '阈值';
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_5.Limits = [0 20];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @StarProssecingButtonPushed, true);
            app.Slider_5.ValueChangingFcn = createCallbackFcn(app, @Slider_5ValueChanging, true);
            app.Slider_5.Value = 3;
            app.Slider_6Label = uilabel(app.UIFigure);
            app.Slider_6Label.Text = '阈值';
            app.Slider_6 = uislider(app.UIFigure);
            app.Slider_6.Limits = [0 20];
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @StarProssecingButtonPushed, true);
            app.Slider_6.ValueChangingFcn = createCallbackFcn(app, @Slider_6ValueChanging, true);
            app.Slider_6.Value = 10;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '采用灰度阈值分割法';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '1、记录最小圆位置';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '2、记录最大圆位置';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '3、对上述两圆进行裁切';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = ' 灰度值';
            app.Slider_7 = uislider(app.UIFigure);
            app.Slider_7.Limits = [0 255];
            app.Slider_7.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Slider_7.ValueChangingFcn = createCallbackFcn(app, @Slider_7ValueChanging, true);
            app.Slider_7.Value = 128;
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '边缘敏感度阈值 最小值';
            app.r_min_value_2 = uieditfield(app.UIFigure, 'numeric');
            app.r_min_value_2.Limits = [0 1];
            app.r_min_value_2.ValueChangedFcn = createCallbackFcn(app, @r_min_value_2ValueChanged, true);
            app.r_min_value_2.Value = 0.1;
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '边缘敏感度阈值 最大值';
            app.r_min_value_3 = uieditfield(app.UIFigure, 'numeric');
            app.r_min_value_3.Limits = [0 1];
            app.r_min_value_3.ValueChangedFcn = createCallbackFcn(app, @r_min_value_3ValueChanged, true);
            app.r_min_value_3.Value = 0.2;
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ItemsData = {'0', '1'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '0';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.Editable = 'off';
            app.EditField_4.Value = '(X,Y,R)';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_5.Editable = 'off';
            app.EditField_5.Value = '(X,Y,R)';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '值';
            app.r_max_value = uieditfield(app.UIFigure, 'numeric');
            app.r_max_value.Limits = [0 500];
            app.r_max_value.ValueChangedFcn = createCallbackFcn(app, @r_max_valueValueChanged2, true);
            app.r_max_value.Value = 20;
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.r_max.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = houghcircle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end